classdef terpunkto3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        NumberofpointstosampleeitherforentirecloudorforeachclassLabel  matlab.ui.control.Label
        NumberofpointstosampleEditField  matlab.ui.control.NumericEditField
        CalculatecolorfeaturesCheckBox  matlab.ui.control.CheckBox
        ScalesforheirarchicaljustenterminandmaxEditFieldLabel  matlab.ui.control.Label
        scales                          matlab.ui.control.EditField
        NeighborhoodMethodButtonGroup   matlab.ui.container.ButtonGroup
        Heir                            matlab.ui.control.RadioButton
        RadiusButton                    matlab.ui.control.RadioButton
        CalculateintensityfeaturesCheckBox  matlab.ui.control.CheckBox
        SubsampleschemeButtonGroup      matlab.ui.container.ButtonGroup
        entire                          matlab.ui.control.RadioButton
        eachclass                       matlab.ui.control.RadioButton
        XindSpinnerLabel                matlab.ui.control.Label
        Xind                            matlab.ui.control.Spinner
        YindSpinnerLabel                matlab.ui.control.Label
        Yind                            matlab.ui.control.Spinner
        ZindSpinnerLabel                matlab.ui.control.Label
        Zind                            matlab.ui.control.Spinner
        redSpinnerLabel                 matlab.ui.control.Label
        red                             matlab.ui.control.Spinner
        greenSpinnerLabel               matlab.ui.control.Label
        green                           matlab.ui.control.Spinner
        blueSpinnerLabel                matlab.ui.control.Label
        blue                            matlab.ui.control.Spinner
        NxSpinnerLabel                  matlab.ui.control.Label
        Nx                              matlab.ui.control.Spinner
        NySpinnerLabel                  matlab.ui.control.Label
        Ny                              matlab.ui.control.Spinner
        NzSpinnerLabel                  matlab.ui.control.Label
        Nz                              matlab.ui.control.Spinner
        LabelSpinnerLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Spinner
        IntensitySpinnerLabel           matlab.ui.control.Label
        Intensity                       matlab.ui.control.Spinner
        PointcloudindicesLabel          matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        functionEditFieldLabel          matlab.ui.control.Label
        functionEditField               matlab.ui.control.EditField
        outputfeaturesnameEditFieldLabel  matlab.ui.control.Label
        outputfeaturesnameEditField     matlab.ui.control.EditField
        RunterpunktoButtonGroup         matlab.ui.container.ButtonGroup
        DontrunButton                   matlab.ui.control.RadioButton
        RunoncewithcurrentsettingsButton  matlab.ui.control.RadioButton
        FullworkflowignoressubsampleschemeButton  matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function GeneratecodeButtonPushed(app, event)
            inname = app.FilenameEditField.Value;
            indices = [app.Xind.Value,app.Yind.Value,app.Zind.Value,app.red.Value,app.green.Value,app.blue.Value,app.Nx.Value,app.Ny.Value,app.Nz.Value,app.Label.Value,app.Intensity.Value];
            scale = str2num(app.scales.Value);
%             app.Heir.Tag
%             app.SubsampleschemeButtonGroup.SelectedObject.Tag
            switch(app.SubsampleschemeButtonGroup.SelectedObject.Tag)
             
                case 'entire'
                    subsampleflag = 1;
                case 'eachclass'
                    subsampleflag = 0;
            end
                  
         colorflag = app.CalculatecolorfeaturesCheckBox.Value;
         intensityflag = app.CalculateintensityfeaturesCheckBox.Value;
         
%          app.NeighborhoodMethodButtonGroup.SelectedObject.Tag
         switch(app.NeighborhoodMethodButtonGroup.SelectedObject.Tag)
             case 'Heir'
                method = 2;
             case 'RadiusButton'
                method = 1;
         end
          numpts_subsample = app.NumberofpointstosampleEditField.Value;
          minscale = min(scale);
          maxscale = max(scale);
         out = ['terpunkto2(''',inname,''',[',num2str(indices),'],[',num2str(scale),'],',num2str(subsampleflag),...
             ',',num2str(intensityflag),',',num2str(colorflag),',',num2str(numpts_subsample),',',...
             num2str(method),',',num2str(minscale),',',num2str(maxscale),')'];
         app.functionEditField.Value = out;
         save('terpunkto_inputs','inname','indices','scale','subsampleflag','intensityflag','colorflag','numpts_subsample','method','minscale','maxscale')
         
         switch app.RunterpunktoButtonGroup.SelectedObject.Tag
             case 'dont'
                 pause(1)
             case 'runonce'
                t = terpunkto2(inname,indices,scale,subsampleflag,intensityflag,colorflag,numpts_subsample,method,minscale,maxscale);
            
                save(['terpunkto_features_',app.outputfeaturesnameEditField.Value,'_',date],'t')
             case 'full'
                 disp('calculating features for training points')
                 t1 = terpunkto2(inname,indices,scale,0,intensityflag,colorflag,numpts_subsample,method,minscale,maxscale);
                 save(['terpunkto_features_TRAIN_',app.outputfeaturesnameEditField.Value,'_',date],'t1')
                 
                 disp('calculating features for full cloud')
                 t2 = terpunkto2(inname,indices,scale,1,intensityflag,colorflag,numpts_subsample*10,method,minscale,maxscale);
                 save(['terpunkto_features_TEST_',app.outputfeaturesnameEditField.Value,'_',date],'t2')
                 
                 x1 = [t1.geom,t1.slope];
                 x2 = [t2.geom,t2.slope];
                 
                 if colorflag
                     if method == 1
                         x1 = [x1,t1.LABmean,t1.stdLAB];
                         x2 = [x2,t2.LABmean,t2.stdLAB];
                         numsplits = 1000;
                     elseif method == 2
                         x1 = [x1,t1.meanColor,t1.stdColor];
                         x2 = [x2,t2.meanColor,t2.stdColor];
                         numsplits = 80;
                     end
                 end
                 
                 if intensityflag
                     x1 = [x1,t1.intensity];
                     x2 = [x2,t2.intensity];
                 end
            
                 y = t1.truth;
                 disp('training Random Forest')
                 
                 tb = TreeBagger(100,x1,y,'maxnumsplits',numsplits);
                 
                 disp('creating predictions')
                 [labels,score] = predict(tb,x2);
                 labels = str2double(labels);
                 
                 disp('writing output')
                 
                 outname = ['terpunkto_outputCloud_',app.outputfeaturesnameEditField.Value,'_',date];
                 writematrix([t2.points,labels,score],outname)
                 
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofpointstosampleeitherforentirecloudorforeachclassLabel = uilabel(app.UIFigure);
            app.NumberofpointstosampleeitherforentirecloudorforeachclassLabel.Text = {'Number of points to sample'; ' (either for entire cloud or for each class)'; '(for full workflow, full cloud samples 10 times'; 'this many points)'};
            app.NumberofpointstosampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculatecolorfeaturesCheckBox = uicheckbox(app.UIFigure);
            app.CalculatecolorfeaturesCheckBox.Text = 'Calculate color features';
            app.CalculatecolorfeaturesCheckBox.Value = true;
            app.ScalesforheirarchicaljustenterminandmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ScalesforheirarchicaljustenterminandmaxEditFieldLabel.Text = {'Scales '; '(for heirarchical, just enter min and max)'};
            app.scales = uieditfield(app.UIFigure, 'text');
            app.scales.Value = '[1,0.75,0.5,0.25,0.1]';
            app.NeighborhoodMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.NeighborhoodMethodButtonGroup.Title = 'Neighborhood Method';
            app.Heir = uiradiobutton(app.NeighborhoodMethodButtonGroup);
            app.Heir.Tag = 'Heir';
            app.Heir.Text = 'Heirarchical subsampling';
            app.Heir.Value = true;
            app.RadiusButton = uiradiobutton(app.NeighborhoodMethodButtonGroup);
            app.RadiusButton.Tag = 'RadiusButton';
            app.RadiusButton.Text = 'Radius';
            app.CalculateintensityfeaturesCheckBox = uicheckbox(app.UIFigure);
            app.CalculateintensityfeaturesCheckBox.Text = 'Calculate intensity features';
            app.SubsampleschemeButtonGroup = uibuttongroup(app.UIFigure);
            app.SubsampleschemeButtonGroup.Title = 'Subsample scheme';
            app.entire = uiradiobutton(app.SubsampleschemeButtonGroup);
            app.entire.Tag = 'entire';
            app.entire.Text = 'Subsample entire cloud (prediction)';
            app.entire.Value = true;
            app.eachclass = uiradiobutton(app.SubsampleschemeButtonGroup);
            app.eachclass.Tag = 'eachclass';
            app.eachclass.Text = 'Subsample N pts for each class (training)';
            app.XindSpinnerLabel = uilabel(app.UIFigure);
            app.XindSpinnerLabel.Text = 'X ind';
            app.Xind = uispinner(app.UIFigure);
            app.Xind.Limits = [0 50];
            app.Xind.Value = 1;
            app.YindSpinnerLabel = uilabel(app.UIFigure);
            app.YindSpinnerLabel.Text = 'Y ind';
            app.Yind = uispinner(app.UIFigure);
            app.Yind.Limits = [0 50];
            app.Yind.Value = 2;
            app.ZindSpinnerLabel = uilabel(app.UIFigure);
            app.ZindSpinnerLabel.Text = 'Z ind';
            app.Zind = uispinner(app.UIFigure);
            app.Zind.Limits = [0 50];
            app.Zind.Value = 3;
            app.redSpinnerLabel = uilabel(app.UIFigure);
            app.redSpinnerLabel.Text = 'red';
            app.red = uispinner(app.UIFigure);
            app.red.Limits = [0 50];
            app.red.Value = 4;
            app.greenSpinnerLabel = uilabel(app.UIFigure);
            app.greenSpinnerLabel.Text = 'green';
            app.green = uispinner(app.UIFigure);
            app.green.Limits = [0 50];
            app.green.Value = 5;
            app.blueSpinnerLabel = uilabel(app.UIFigure);
            app.blueSpinnerLabel.Text = 'blue';
            app.blue = uispinner(app.UIFigure);
            app.blue.Limits = [0 50];
            app.blue.Value = 6;
            app.NxSpinnerLabel = uilabel(app.UIFigure);
            app.NxSpinnerLabel.Text = 'Nx';
            app.Nx = uispinner(app.UIFigure);
            app.Nx.Limits = [0 50];
            app.Nx.Value = 7;
            app.NySpinnerLabel = uilabel(app.UIFigure);
            app.NySpinnerLabel.Text = 'Ny';
            app.Ny = uispinner(app.UIFigure);
            app.Ny.Limits = [0 50];
            app.Ny.Value = 8;
            app.NzSpinnerLabel = uilabel(app.UIFigure);
            app.NzSpinnerLabel.Text = 'Nz';
            app.Nz = uispinner(app.UIFigure);
            app.Nz.Limits = [0 50];
            app.Nz.Value = 9;
            app.LabelSpinnerLabel = uilabel(app.UIFigure);
            app.Label = uispinner(app.UIFigure);
            app.Label.Limits = [0 50];
            app.Label.Value = 10;
            app.IntensitySpinnerLabel = uilabel(app.UIFigure);
            app.IntensitySpinnerLabel.Text = 'Intensity';
            app.Intensity = uispinner(app.UIFigure);
            app.Intensity.Limits = [0 50];
            app.Intensity.Value = 11;
            app.PointcloudindicesLabel = uilabel(app.UIFigure);
            app.PointcloudindicesLabel.Text = 'Point cloud indices';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecodeButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.functionEditFieldLabel = uilabel(app.UIFigure);
            app.functionEditFieldLabel.Text = 'function';
            app.functionEditField = uieditfield(app.UIFigure, 'text');
            app.outputfeaturesnameEditFieldLabel = uilabel(app.UIFigure);
            app.outputfeaturesnameEditFieldLabel.Text = 'output features name';
            app.outputfeaturesnameEditField = uieditfield(app.UIFigure, 'text');
            app.outputfeaturesnameEditField.Value = 'myCloud';
            app.RunterpunktoButtonGroup = uibuttongroup(app.UIFigure);
            app.RunterpunktoButtonGroup.Title = 'Run terpunkto';
            app.DontrunButton = uiradiobutton(app.RunterpunktoButtonGroup);
            app.DontrunButton.Tag = 'dont';
            app.DontrunButton.Text = 'Don''t run';
            app.DontrunButton.Value = true;
            app.RunoncewithcurrentsettingsButton = uiradiobutton(app.RunterpunktoButtonGroup);
            app.RunoncewithcurrentsettingsButton.Tag = 'runonce';
            app.RunoncewithcurrentsettingsButton.Text = 'Run once with current settings';
            app.FullworkflowignoressubsampleschemeButton = uiradiobutton(app.RunterpunktoButtonGroup);
            app.FullworkflowignoressubsampleschemeButton.Tag = 'full';
            app.FullworkflowignoressubsampleschemeButton.Text = 'Full workflow (ignores subsample scheme)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = terpunkto3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end